classdef dct_compression_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab1                            matlab.ui.container.Tab
        NextButton                      matlab.ui.control.Button
        UploadImageButton               matlab.ui.control.Button
        ImageCompressionandReconstructionLabel  matlab.ui.control.Label
        OriginalImageDisplay            matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        NextButton_2                    matlab.ui.control.Button
        QualityFactorSlider             matlab.ui.control.Slider
        QualityFactorSliderLabel        matlab.ui.control.Label
        BitsPerPixelEditField           matlab.ui.control.NumericEditField
        BitsPerPixelEditFieldLabel      matlab.ui.control.Label
        SSIMEditField                   matlab.ui.control.NumericEditField
        SSIMEditFieldLabel              matlab.ui.control.Label
        CompressionRatioEditField       matlab.ui.control.NumericEditField
        CompressionRatioEditFieldLabel  matlab.ui.control.Label
        MSEEditField                    matlab.ui.control.NumericEditField
        MSEEditFieldLabel               matlab.ui.control.Label
        PSNRdBEditField                 matlab.ui.control.NumericEditField
        PSNRdBEditFieldLabel            matlab.ui.control.Label
        DecompressedMessageLabel        matlab.ui.control.Label
        ReconstructButton               matlab.ui.control.Button
        CompressedMessageLabel          matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        CompressedImageSizeKBEditField  matlab.ui.control.NumericEditField
        CompressedImageSizeKBEditFieldLabel  matlab.ui.control.Label
        OriginalImageSizeKBEditField    matlab.ui.control.NumericEditField
        OriginalImageSizeKBEditFieldLabel  matlab.ui.control.Label
        CompressButton                  matlab.ui.control.Button
        OriginalImageDisplay2           matlab.ui.control.UIAxes
        ReconstructedImageDisplay       matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        MeasureButton                   matlab.ui.control.Button
        SNRvsQfDisplay                  matlab.ui.control.UIAxes
        MSEvsQfDisplay                  matlab.ui.control.UIAxes
        CompressionRatioVsQfDisplay     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        OriginalImage
        HuffmanEncoded
        HuffmanDict
        QuantizationMatrix
        Rows
        Cols
        NewRows
        NewCols
        Channels
        HuffmanTree  
    end
    
    methods (Access = private)
        

        function D = DCT2(~, block)
            N = 8;
            D = zeros(N, N);
            alpha = @(k) sqrt(1/N) * (k == 0) + sqrt(2/N) * (k ~= 0);
        
            for u = 0:N-1
                for v = 0:N-1
                    sum = 0;
                    for x = 0:N-1
                        for y = 0:N-1
                            sum = sum + block(x+1, y+1) * cos((2*x+1)*u*pi/(2*N)) * cos((2*y+1)*v*pi/(2*N));
                        end
                    end
                    D(u+1, v+1) = alpha(u) * alpha(v) * sum;
                end
            end
        end

        function output = IDCT2(~, input)
            N = 8;  
            output = zeros(N, N);  
            alpha = @(k) sqrt(1/N) * (k==0) + sqrt(2/N) * (k~=0);
        
            for x = 0:N-1
                for y = 0:N-1
                    sum = 0;
                    for u = 0:N-1
                        for v = 0:N-1
                            sum = sum + alpha(u) * alpha(v) * input(u+1, v+1) * cos((pi*(2*x+1)*u)/(2*N)) * cos((pi*(2*y+1)*v)/(2*N));
                        end
                    end
                    output(x+1, y+1) = sum;
                end
            end
        end

        function zigzag_vector = zigzag(~, block)
           
            zigzag_order = [
                1  2  6  7 15 16 28 29;
                3  5  8 14 17 27 30 43;
                4  9 13 18 26 31 42 44;
                10 12 19 25 32 41 45 54;
                11 20 24 33 40 46 53 55;
                21 23 34 39 47 52 56 61;
                22 35 38 48 51 57 60 62;
                36 37 49 50 58 59 63 64
            ];

            zigzag_vector = zeros(1, 64);
            for i = 1 : 64
                [row, col] = find(zigzag_order == i);
                zigzag_vector(i) = block(row, col);
            end
        end
        
        function zero_truncated_vector = zero_truncation(~, zigzag_vector)
          
            last_non_zero_idx = 1;
            for i = 64 : -1 : 1
                if zigzag_vector(i) ~= 0
                    last_non_zero_idx = i;
                    break;
                end
            end

            zero_truncated_vector = [zigzag_vector(1 : last_non_zero_idx)'; 9999]; %9999 means end of block
        end


        function reverse_zero_truncated_blocks = reverse_zero_truncation(~, zero_truncated_stream)
            reverse_zero_truncated_blocks = [];
            current_block = [];
        
            for i = 1:length(zero_truncated_stream)
                val = zero_truncated_stream(i);
                if val == 9999
                    % End of current block: pad to 64
                    padded_block = [current_block; zeros(64 - length(current_block), 1)];
                    reverse_zero_truncated_blocks = [reverse_zero_truncated_blocks, padded_block];  % Add as column
                    current_block = [];
                else
                    current_block = [current_block; val];
                end
            end
        end

        function block = Reverse_Zigzag(~, zigzag_vector)
            zigzag_order = [
                1  2  6  7 15 16 28 29;
                3  5  8 14 17 27 30 43;
                4  9 13 18 26 31 42 44;
                10 12 19 25 32 41 45 54;
                11 20 24 33 40 46 53 55;
                21 23 34 39 47 52 56 61;
                22 35 38 48 51 57 60 62;
                36 37 49 50 58 59 63 64
            ];
        
            block = zeros(8, 8);
            for i = 1:64
                [row, col] = find(zigzag_order == i);
                block(row, col) = zigzag_vector(i);
            end
        end

        function reconstructed_img = Reconstruct_Image(app, reverse_zero_truncated, Q, rows, cols, new_rows, new_cols, channels)
        
            reconstructed_img = zeros(new_rows, new_cols, channels);
            idx = 1;
            for ch = 1:channels
                for i = 1:8:new_rows
                    for j = 1:8:new_cols
                        inverse_zigzag = Reverse_Zigzag(app, reverse_zero_truncated(:, idx));
                        idx = idx + 1;
                        dequant_block = inverse_zigzag .* Q;
                        reconstructed_img(i:i+7, j:j+7, ch) = IDCT2(app, dequant_block);
                    end
                end
            end
        
            reconstructed_img = reconstructed_img + 128;
            reconstructed_img = reconstructed_img(1:rows, 1:cols, :);
            reconstructed_img(reconstructed_img < 0) = 0;
            reconstructed_img(reconstructed_img > 255) = 255;
            reconstructed_img = uint8(reconstructed_img);
        end

        
        function [unique_symbols, probabilities] = calculateProbabilities(~, symbols)

            T = tabulate(symbols);
            unique_symbols = T(:,1);
            counts = T(:,2);
            probabilities = counts / sum(counts);

        end
        
        function tree = buildHuffmanTree(~, symbols, probabilities)
            n = numel(symbols);
            nodes = cell(1, n);
            for i = 1:n
                nodes{i} = struct('symbol', symbols(i), 'prob', probabilities(i), 'left', [], 'right', []);
            end
    
            while numel(nodes) > 1
                % Sort nodes by probability
                [~, idx] = sort(cellfun(@(x) x.prob, nodes));
                nodes = nodes(idx);
                
                % Merge two smallest
                left = nodes{1};
                right = nodes{2};
                merged = struct('symbol', [], 'prob', left.prob + right.prob, 'left', left, 'right', right);
               
                % Replace nodes
                nodes = [{merged}, nodes(3:end)];
            end
            tree = nodes{1};
        end

        function dict = generateHuffmanDict(~, tree)
            dict = containers.Map('KeyType', class(tree.symbol), 'ValueType', 'any');
            traverse(tree, '');
    
            function traverse(node, code)
                if isempty(node.left) && isempty(node.right)
                    dict(node.symbol) = code;
                else
                    if ~isempty(node.left)
                        traverse(node.left, [code '0']);
                    end
                    if ~isempty(node.right)
                        traverse(node.right, [code '1']);
                    end
                end
            end
        end

        function encoded_data = huffmanEncode(app, symbols)
            [unique_symbols, probabilities] = calculateProbabilities(app, symbols);
            tree = buildHuffmanTree(app, unique_symbols, probabilities);
            dict = generateHuffmanDict(app, tree);
            app.HuffmanDict = dict;
            app.HuffmanTree = tree; 

            % Encode
            encoded_data = '';
            for i = 1:length(symbols)
                encoded_data = [encoded_data dict(symbols(i))]; %#ok<AGROW>
            end
        end

        function decoded_data = huffmanDecode(app, encoded_data)
            tree = app.HuffmanTree;
            decoded_data = [];
                    node = tree;
            for i = 1:length(encoded_data)
                if encoded_data(i) == '0'
                    node = node.left;
                else
                    node = node.right;
                end
                if isempty(node.left) && isempty(node.right)
                    decoded_data = [decoded_data node.symbol]; %#ok<AGROW>
                    node = tree;
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            app.CompressedMessageLabel.Text = '';
            [file, path] = uigetfile({'*.tif;*.tiff;*.jpg;*.jpeg;*.png;*.bmp','Image Files(*.tif, *.tiff, *.jpg, *.jpeg, *.png, *.bmp)'}, 'Upload an Image');
        
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file was selected.', 'Upload Cancelled');
                return;
            end

            image_file = fullfile(path, file);
            img = imread(image_file);
            app.OriginalImage = img;
            % disp(size(img, 3));
            imshow(img, 'Parent', app.OriginalImageDisplay);
            imshow(img, 'Parent', app.OriginalImageDisplay2);
            app.NextButton.Enable = 'on';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
            app.CompressButton.Enable = 'on';
        end

        % Button pushed function: CompressButton
        function CompressButtonPushed(app, event)
            app.CompressButton.Enable = 'off';
            app.ReconstructButton.Enable = 'off';
            app.CompressedMessageLabel.Text = '';
            app.DecompressedMessageLabel.Text = '';
            app.OriginalImageSizeKBEditField.Value = 0;
            app.CompressedImageSizeKBEditField.Value = 0;
            app.PSNRdBEditField.Value = 0;
            app.MSEEditField.Value = 0;
            app.SSIMEditField.Value = 0;
            app.CompressionRatioEditField.Value = 0;
            app.BitsPerPixelEditField.Value = 0;
            drawnow;
            original_img = app.OriginalImage;
            [rows, cols, channels] = size(original_img);
            new_rows = ceil(rows/8) * 8;
            new_cols = ceil(cols/8) * 8;
            
            app.Rows = rows;
            app.Cols = cols;
            app.NewRows = new_rows;
            app.NewCols = new_cols;
            app.Channels = channels;

            padded_img = zeros(new_rows, new_cols, channels);
            padded_img(1:rows, 1:cols, :) = original_img;
    
            Q = [16 11 10 16 24 40 51 61;
                 12 12 14 19 26 58 60 55;
                 14 13 16 24 40 57 69 56;
                 14 17 22 29 51 87 80 62;
                 18 22 37 56 68 109 103 77;
                 24 35 55 64 81 104 113 92;
                 49 64 78 87 103 121 120 101;
                 72 92 95 98 112 100 103 99];

            quality_factor = app.QualityFactorSlider.Value;
            if quality_factor < 50
                scale = 50 / quality_factor;
            else
                scale = (100 - quality_factor) / 50;
            end
            Q = round(Q * scale);
            Q(Q < 1) = 1;
            Q(Q > 255) = 255;
            app.QuantizationMatrix = Q;

            zero_truncated_stream = [];

            for ch = 1:channels
                channel = padded_img(:, :, ch);
                channel = channel - 128;
                for i = 1:8:new_rows
                    for j = 1:8:new_cols
                        block = channel(i:i+7, j:j+7);
                        dct_block = DCT2(app, block);
                        quantized_block = round(dct_block ./ Q);
                        zigzag_vector = zigzag(app, quantized_block);
                        zero_truncated_stream = [zero_truncated_stream; zero_truncation(app, zigzag_vector)];
                    end
                end
            end

            huffman_encoded = huffmanEncode(app, zero_truncated_stream);
            app.HuffmanEncoded = huffman_encoded;
            app.CompressedImageSizeKBEditField.Value = length(huffman_encoded) / (8 * 1024);
            app.OriginalImageSizeKBEditField.Value = numel(original_img) / 1024;
            app.CompressedMessageLabel.Text = "✅ Image has been compressed successfully!";
            app.CompressButton.Enable = 'on';
            app.ReconstructButton.Enable = 'on';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.CompressedMessageLabel.Text = '';
            app.DecompressedMessageLabel.Text = '';
            cla(app.OriginalImageDisplay);
            cla(app.OriginalImageDisplay2);
            cla(app.ReconstructedImageDisplay);
            cla(app.CompressionRatioVsQfDisplay);
            cla(app.SNRvsQfDisplay);
            cla(app.MSEvsQfDisplay);
            app.OriginalImageSizeKBEditField.Value = 0;
            app.CompressedImageSizeKBEditField.Value = 0;
            app.PSNRdBEditField.Value = 0;
            app.MSEEditField.Value = 0;
            app.SSIMEditField.Value = 0;
            app.CompressionRatioEditField.Value = 0;
            app.BitsPerPixelEditField.Value = 0;
            app.HuffmanEncoded = [];
            app.HuffmanDict = [];
            app.QuantizationMatrix = [];
            app.Rows = [];
            app.Cols = [];
            app.NewRows = [];
            app.NewCols = [];
            app.NextButton.Enable = 'off';
            app.CompressButton.Enable = 'off';
            app.ReconstructButton.Enable = 'off';
            app.TabGroup.SelectedTab = app.Tab1;
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            app.ReconstructButton.Enable = 'off';
            drawnow;
            huffman_decoded = huffmanDecode(app, app.HuffmanEncoded);
            reverse_zero_truncated = reverse_zero_truncation(app, huffman_decoded);
            reconstructed_img = Reconstruct_Image(app, reverse_zero_truncated, app.QuantizationMatrix, app.Rows, app.Cols, app.NewRows, app.NewCols, app.Channels);
            app.DecompressedMessageLabel.Text = "✅ Image has been reconstructed successfully!";
            imshow(reconstructed_img, 'Parent', app.ReconstructedImageDisplay);

            original = double(app.OriginalImage);
            reconstructed_img = double(reconstructed_img);
            
            mse = mean((double(original(:)) - double(reconstructed_img(:))).^2);

            psnr_val = 10 * log10(255^2 / mse);
            ssim_val = ssim(uint8(reconstructed_img), uint8(original));
            
            compression_ratio = app.OriginalImageSizeKBEditField.Value / app.CompressedImageSizeKBEditField.Value;
            bpp = (app.CompressedImageSizeKBEditField.Value * 1024 * 8) / (size(original,1) * size(original,2));
            
            % Display in UI
            app.PSNRdBEditField.Value = psnr_val;
            app.MSEEditField.Value = mse;
            app.SSIMEditField.Value = ssim_val;
            app.CompressionRatioEditField.Value = compression_ratio;
            app.BitsPerPixelEditField.Value = bpp;

            app.ReconstructButton.Enable = 'on';
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            cla(app.CompressionRatioVsQfDisplay);
            cla(app.SNRvsQfDisplay);
            cla(app.MSEvsQfDisplay);
            drawnow;
            app.MeasureButton.Enable = 'off';
            QF = [1 5 10 15 30 50 80 90 95 100];
            psnr_val = [];
            mse = [];
            compression_ratio = [];
            for i = 1 : length(QF)
                app.QualityFactorSlider.Value = QF(i);
                CompressButtonPushed(app, event);
                ReconstructButtonPushed(app, event);
                psnr_val = [psnr_val app.PSNRdBEditField.Value];
                mse = [mse app.MSEEditField.Value];
                compression_ratio = [compression_ratio app.CompressionRatioEditField.Value];
            end
            plot(app.CompressionRatioVsQfDisplay, QF, compression_ratio, 'LineWidth', 2);
            xlabel(app.CompressionRatioVsQfDisplay, 'Quality Factor');
            ylabel(app.CompressionRatioVsQfDisplay, 'Compression Ratio');
            title(app.CompressionRatioVsQfDisplay, 'Compression Ratio vs Quality Factor');
            grid(app.CompressionRatioVsQfDisplay, 'on');

            plot(app.SNRvsQfDisplay, QF, psnr_val, 'LineWidth', 2);
            xlabel(app.SNRvsQfDisplay, 'Quality Factor');
            ylabel(app.SNRvsQfDisplay, 'PSNR (dB)');
            title(app.SNRvsQfDisplay, 'Peak Signal to Noise Ratio vs Quality Factor');
            grid(app.SNRvsQfDisplay, 'on');

            plot(app.MSEvsQfDisplay, QF, mse, 'LineWidth', 2);
            xlabel(app.MSEvsQfDisplay, 'Quality Factor');
            ylabel(app.MSEvsQfDisplay, 'MSE');
            title(app.MSEvsQfDisplay, 'Mean Squared Error vs Quality Factor');
            grid(app.MSEvsQfDisplay, 'on');
            app.MeasureButton.Enable = 'on';


        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab3;
            app.MeasureButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab1';
            app.OriginalImageDisplay = uiaxes(app.Tab1);
            zlabel(app.OriginalImageDisplay, 'Z')
            app.OriginalImageDisplay.XTick = [];
            app.OriginalImageDisplay.YTick = [];
            app.ImageCompressionandReconstructionLabel = uilabel(app.Tab1);
            app.ImageCompressionandReconstructionLabel.Text = 'Image Compression and Reconstruction';
            app.UploadImageButton = uibutton(app.Tab1, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.NextButton = uibutton(app.Tab1, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ReconstructedImageDisplay = uiaxes(app.Tab2);
            title(app.ReconstructedImageDisplay, 'Reconstructed Image')
            zlabel(app.ReconstructedImageDisplay, 'Z')
            app.ReconstructedImageDisplay.XTick = [];
            app.ReconstructedImageDisplay.YTick = [];
            app.OriginalImageDisplay2 = uiaxes(app.Tab2);
            title(app.OriginalImageDisplay2, 'Original Image')
            zlabel(app.OriginalImageDisplay2, 'Z')
            app.OriginalImageDisplay2.XTick = [];
            app.OriginalImageDisplay2.YTick = [];
            app.CompressButton = uibutton(app.Tab2, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressButtonPushed, true);
            app.CompressButton.Text = 'Compress';
            app.OriginalImageSizeKBEditFieldLabel = uilabel(app.Tab2);
            app.OriginalImageSizeKBEditFieldLabel.Text = 'Original Image Size (KB)';
            app.OriginalImageSizeKBEditField = uieditfield(app.Tab2, 'numeric');
            app.CompressedImageSizeKBEditFieldLabel = uilabel(app.Tab2);
            app.CompressedImageSizeKBEditFieldLabel.Text = 'Compressed Image Size (KB)';
            app.CompressedImageSizeKBEditField = uieditfield(app.Tab2, 'numeric');
            app.ClearButton = uibutton(app.Tab2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CompressedMessageLabel = uilabel(app.Tab2);
            app.CompressedMessageLabel.Text = '';
            app.ReconstructButton = uibutton(app.Tab2, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.DecompressedMessageLabel = uilabel(app.Tab2);
            app.DecompressedMessageLabel.Text = '';
            app.PSNRdBEditFieldLabel = uilabel(app.Tab2);
            app.PSNRdBEditFieldLabel.Text = 'PSNR (dB)';
            app.PSNRdBEditField = uieditfield(app.Tab2, 'numeric');
            app.MSEEditFieldLabel = uilabel(app.Tab2);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.MSEEditField = uieditfield(app.Tab2, 'numeric');
            app.CompressionRatioEditFieldLabel = uilabel(app.Tab2);
            app.CompressionRatioEditFieldLabel.Text = 'Compression Ratio';
            app.CompressionRatioEditField = uieditfield(app.Tab2, 'numeric');
            app.SSIMEditFieldLabel = uilabel(app.Tab2);
            app.SSIMEditFieldLabel.Text = 'SSIM';
            app.SSIMEditField = uieditfield(app.Tab2, 'numeric');
            app.BitsPerPixelEditFieldLabel = uilabel(app.Tab2);
            app.BitsPerPixelEditFieldLabel.Text = 'Bits Per Pixel	';
            app.BitsPerPixelEditField = uieditfield(app.Tab2, 'numeric');
            app.QualityFactorSliderLabel = uilabel(app.Tab2);
            app.QualityFactorSliderLabel.Text = 'Quality Factor';
            app.QualityFactorSlider = uislider(app.Tab2);
            app.QualityFactorSlider.Limits = [1 100];
            app.QualityFactorSlider.Value = 1;
            app.NextButton_2 = uibutton(app.Tab2, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.CompressionRatioVsQfDisplay = uiaxes(app.Tab3);
            title(app.CompressionRatioVsQfDisplay, 'Compression Ratio vs QF')
            zlabel(app.CompressionRatioVsQfDisplay, 'Z')
            app.MSEvsQfDisplay = uiaxes(app.Tab3);
            title(app.MSEvsQfDisplay, 'MSE vs QF')
            zlabel(app.MSEvsQfDisplay, 'Z')
            app.SNRvsQfDisplay = uiaxes(app.Tab3);
            title(app.SNRvsQfDisplay, 'SNR vs QF')
            zlabel(app.SNRvsQfDisplay, 'Z')
            app.MeasureButton = uibutton(app.Tab3, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dct_compression_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end